classdef LuminanceMapManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CropButton                      matlab.ui.control.Button
        AppmessageLabel                 matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ActualrecalibrationvalueLabel   matlab.ui.control.Label
        RecalibrationValueEditField     matlab.ui.control.NumericEditField
        RecalibrationValueEditFieldLabel  matlab.ui.control.Label
        AddrecalibrationareaButton      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ROInumberEditField              matlab.ui.control.NumericEditField
        ROInumberEditFieldLabel         matlab.ui.control.Label
        DeleteROIButton                 matlab.ui.control.Button
        ROINameInput                    matlab.ui.control.EditField
        AddROIButton                    matlab.ui.control.Button
        SpectrummapButton               matlab.ui.control.Button
        DeleteactualluminancemapButton  matlab.ui.control.Button
        LuminancemapsListBox            matlab.ui.control.ListBox
        LuminancemapsListBoxLabel       matlab.ui.control.Label
        MapNameInput                    matlab.ui.control.EditField
        AddluminancemapButton           matlab.ui.control.Button
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        IncludesubfolderDropDown        matlab.ui.control.DropDown
        IncludesubfolderDropDownLabel   matlab.ui.control.Label
        ExtensionDropDown               matlab.ui.control.DropDown
        ExtensionDropDownLabel          matlab.ui.control.Label
        ColormapscaleDropDown           matlab.ui.control.DropDown
        ColormapscaleDropDownLabel      matlab.ui.control.Label
        Log10scaleDropDown              matlab.ui.control.DropDown
        Log10scaleDropDownLabel         matlab.ui.control.Label
        upperLimit                      matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        bottomLimit                     matlab.ui.control.NumericEditField
        ViewrangeLabel                  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        actualSample = [];
        lastPath = [];
    end

    methods (Access = public)
        function showMessage(app, message)
            app.AppmessageLabel.Text = message;
            drawnow();
        end

        function searchMap(app)
            paths = fullfile(pwd, "Maps");
            paths = getAllFiles(paths, ".mat");
            if isempty(paths)
                app.LuminancemapsListBox.Items = {};
                app.LuminancemapsListBox.ItemsData = {};
                return;
            end
            [~, name, ~] = fileparts(paths);
            name = string(name);
            names = arrayfun(@(x) extractName(x), name);
            app.LuminancemapsListBox.Items = reshape(names, 1,[]);
            app.LuminancemapsListBox.ItemsData = reshape(paths, 1,[]);

            function name = extractName(inputName)
                x = strsplit(inputName, "#");
                name = x(1);
            end
            drawnow();
        end

        function showData(app)
            if isempty(app.actualSample)
                return;
            end
            showImage(app, app.UIAxes);
            app.actualSample.plotROIs(app.UIAxes);
            app.Label.Text = string(app.actualSample.ReCalibrationValue);
        end

        function showImage(app, target)
            cla(target);

            scaleMode = app.ColormapscaleDropDown.Value;
            viewMode = "originalMap"; %app.ViewmodeDropDown.Value;
            log10mode = app.Log10scaleDropDown.Value;
            upLimit = app.upperLimit.Value;
            downLimit = app.bottomLimit.Value;

            Y = ImageProcessor.rgb2y(app.actualSample.getHDR, viewMode, [], log10mode);
            Y(Y > upLimit | Y < downLimit) = nan;

            imagesc(target, Y, "CDataMapping","scaled")
            target.XLim = [1 size(Y, 2)];
            target.YLim = [1 size(Y, 1)];
            colormap(target, scaleMode);
            cbar = colorbar(target);
            cbar.Label.String = '[cd/m^2]' ;
            cbar.FontSize = 16;
            % ylabel(cbar, '[cd/m^2]');

            drawnow();
        end

        function showTable(app)
            if isempty(app.actualSample)
                return;
            end

            tab = getROIsTable(app.actualSample);
            names = tab.Properties.VariableNames;
            app.UITable.ColumnName = names;
            app.UITable.Data = tab;
        end

        function refreshApp(app)
            showData(app);
            showTable(app);
            drawnow();
        end

        function folder = getFolder(app)
            if ~isempty(app.lastPath)
                folder = uigetdir(app.lastPath);
            else
                folder = uigetdir;
            end

            if isnumeric(folder)
                folder = [];
            end
        end

        function file = getFile(app)
            if ~isempty(app.lastPath)
                [filename, pathname] = uigetfile({'*.xlsx';'*.xls'}, "Select a file...", app.lastPath, "MultiSelect", "off");
            else
                [filename, pathname] = uigetfile({'*.xlsx';'*.xls'}, "Select a file...", "MultiSelect", "off");
            end

            if isnumeric(filename)
                file = [];
                return;
            end
            file = fullfile(pathname, filename);
        end


        function exportAll(app)
            newFig = figure('Visible', 'off');
            newAxes = axes(newFig);

            folder = getFolder(app);
            if isempty(folder)
                return;
            end

            name = split(app.actualSample.Name, "#");
            name = name{1};
            folder = fullfile(folder, name);
            if ~exist(folder, "dir")
                mkdir(folder);
            else
                folder = folder + string(datetime("now", "Format","uuuu_MM_dd_HH_mm_ss_SSS"));
                mkdir(folder);
            end

            showImage(app, newAxes);
            newAxes.XTick = [];
            newAxes.YTick = [];
            newAxes.XTickLabel = [];
            newAxes.YTickLabel = [];

            newFig.Units = 'normalized';
            newFig.OuterPosition = [0 0 1 1];

            exportgraphics(newAxes, fullfile(folder, app.actualSample.Name + "_withoutROI.jpg"), 'Resolution', 600);
            app.actualSample.plotROIs(newAxes);

            frame = getframe(newFig);
            im = frame2im(frame);
            imwrite(im,  fullfile(folder, app.actualSample.Name + "_withROI.jpg"), "jpg", "Quality",100, "BitDepth",8);

            close(newFig);

            writetable(app.UITable.Data,  fullfile(folder, "DataTable.xlsx"));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ColormapscaleDropDown.Items = ["turbo", "parula", "hsv", "hot", "cool", "spring", "summer", "autumn", "winter", "gray", "bone", "copper", "pink", "sky", "jet"];
            app.ColormapscaleDropDown.ItemsData = app.ColormapscaleDropDown.Items;

            % app.ROItypeDropDown.Items = ["Rectangle", "Circle", "Ellipse", "Polygon"];
            % app.ROItypeDropDown.ItemsData = {@() ROI_own("rectangle"), @() ROI_own("circle"), @() ROI_own("ellipse"), @() ROI_own("polygon")};

            % app.ViewmodeDropDown.Items = ["Original", "Mesopic", "Fotopic", "Hight luminace", "Medium luminace", "Low luminace"];
            % app.ViewmodeDropDown.ItemsData = ["originalMap", "mesopic", "photopic", "hightLuminance", "mediumLuminance", "lowLuminance"];

            app.ExtensionDropDown.Items = ["NEF", "CR2", "JPG"];
            app.ExtensionDropDown.ItemsData = [".nef", ".cr2", ".jpg"];

            path = fullfile(pwd, "CalibrationModels");

            warning('off', 'MATLAB:MKDIR:DirectoryExists');
            mkdir('CalibrationModels');

            files = getAllFiles(path, ".mat");
            [~, names, ~] = fileparts(files);

            models = arrayfun(@(x) load(x).calibrationModel, files);
            if isempty(names)
                 app.ModelDropDown.Items = "none";
                 app.ModelDropDown.ItemsData = {[]};
            else
                app.ModelDropDown.Items = ["none", names];
                app.ModelDropDown.ItemsData = {[], models};
            end
           

            app.IncludesubfolderDropDown.Items = ["Yes", "No"];
            app.IncludesubfolderDropDown.ItemsData = [true, false];

            app.Log10scaleDropDown.Items = ["Yes", "No"];
            app.Log10scaleDropDown.ItemsData = [true, false];

            mkdir('Maps');
            warning('on', 'MATLAB:MKDIR:DirectoryExists');

            searchMap(app);
            drawnow();
        end

        % Button pushed function: AddluminancemapButton
        function AddluminancemapButtonPushed(app, event)
            folder = getFolder(app);
            if isempty(folder)
                return;
            end

            showMessage(app, "Adding new map...");
            [~, folderName, ~] = fileparts(folder);

            timeID = string(datetime("now", "Format","uuuu_MM_dd_HH_mm_ss_SSS"));
            if isempty(app.MapNameInput.Value)
                name = folderName + "#" + timeID;
            else
                name = app.MapNameInput.Value + "#" + timeID;
            end


            folder = string(folder);
            app.lastPath = folder;

            model = app.ModelDropDown.Value; 

            extension = app.ExtensionDropDown.Value;
            includeSubfolder = app.IncludesubfolderDropDown.Value;

            app.actualSample = Sample(name);
            app.actualSample.createHDRFromFolder(folder, extension, model, includeSubfolder)

            searchMap(app);
            refreshApp(app);
            showMessage(app, "New luminance map is ready.");
        end

        % Button pushed function: DeleteactualluminancemapButton
        function DeleteactualluminancemapButtonPushed(app, event)
            if isempty(app.actualSample)
                return;
            end

            choice = questdlg('Do you really want to delete the sample?', ...
                'Confirmation', ...
                'Yes','No','No');

            if strcmp("Yes", choice)
                name = app.actualSample.Name;
                path = fullfile(pwd, "Maps", name + ".mat");
                delete(path);
                app.actualSample = [];
            end

            searchMap(app);
            cla(app.UIAxes);
            showMessage(app, "Luminance map was deleted.");
        end

        % Clicked callback: LuminancemapsListBox
        function LuminancemapsListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            path = event.Source.ItemsData(item);
            if ~isscalar(path)
                return;
            end
            app.actualSample = load(fullfile(path)).obj;

            showData(app);
            showTable(app);
            drawnow();
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            if isempty(app.actualSample)
                return;
            end

            if isempty(app.ROINameInput.Value)
                name = "ROI_" + string(numel(app.actualSample.ROIs)+1);
            else
                name = app.ROINameInput.Value;
            end

            roi = @() ROI_own("rectangle");
            roi = roi();
            roi.defineByUserInImage([], app.UIAxes);
            app.actualSample.addROI(roi, name);

            refreshApp(app);
            showMessage(app, "New ROI is ready.");
        end

        % Button pushed function: DeleteROIButton
        function DeleteROIButtonPushed(app, event)
            if isempty(app.actualSample)
                return;
            end

            number = app.ROInumberEditField.Value;

            app.actualSample.deleteROI(number);

            refreshApp(app);
            showMessage(app, "ROI was deleted.");
        end

        % Value changed function: ColormapscaleDropDown
        function ColormapscaleDropDownValueChanged(app, event)
            refreshApp(app);
        end

        % Value changed function: Log10scaleDropDown
        function Log10scaleDropDownValueChanged(app, event)
            refreshApp(app);
        end

        % Button pushed function: AddrecalibrationareaButton
        function AddrecalibrationareaButtonPushed(app, event)
            if isempty(app.actualSample)
                return;
            end

            Yref = app.RecalibrationValueEditField.Value;
            % roi = app.ROItypeDropDown.Value;
            roi = ROI_own("rectangle");
            roi.defineByUserInImage([], app.UIAxes);
            Y = roi.getMeanValue(ImageProcessor.rgb2y(app.actualSample.HDR));
            reCalibrationValue = Yref/Y;

            app.actualSample.addRecalibrationValue(reCalibrationValue);

            refreshApp(app);
            showMessage(app, "Calibration value was add.");
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isempty(app.actualSample)
                return;
            end

            showMessage(app, "Exporting...");
            exportAll(app);
            showMessage(app, "Data was exported.");
        end

        % Value changed function: bottomLimit
        function bottomLimitValueChanged(app, event)
            refreshApp(app);
        end

        % Value changed function: upperLimit
        function upperLimitValueChanged(app, event)
            showData(app);
            showTable(app);
        end

        % Button pushed function: SpectrummapButton
        function SpectrummapButtonPushed(app, event)
            screenSize = get(0, 'ScreenSize');
            dialogWidth = 220;
            dialogHeight = 130;
            dialogX = (screenSize(3) - dialogWidth) / 2;
            dialogY = (screenSize(4) - dialogHeight) / 2;

            d = dialog('Position', [dialogX, dialogY, dialogWidth, dialogHeight], 'Name', 'Chosing input files...', 'CloseRequestFcn', @(src, event) closeDialog());

            btn1 = uicontrol('Parent', d, 'Style', 'pushbutton', 'String', 'Spectrum', 'Position', [12, 80, 90, 35], 'Callback', @(src, event) button1Callback(), 'BackgroundColor', [0.8, 0.2, 0.2]);

            btn2 = uicontrol('Parent', d, 'Style', 'pushbutton', 'String', 'Luminance map', 'Position', [118, 80, 90, 35], 'Callback', @(src, event) button2Callback(), 'BackgroundColor', [0.8, 0.2, 0.2]);

            btn3 = uicontrol('Parent', d, 'Style', 'pushbutton', 'String', 'Close and create.', 'Position', [65, 35, 90, 35], 'Callback', @(src, event) button3Callback(), 'BackgroundColor', [0.7, 0.7, 0.7]);

            spectrum = [];
            map = [];
            uiwait(d);

            if isempty(map) | isempty(spectrum)
                return;
            end

            timeID = string(datetime("now", "Format","uuuu_MM_dd_HH_mm_ss_SSS"));
            name = app.MapNameInput.Value;

            if isempty(name)
                name = "Map";
            end

            app.actualSample = Sample(name + "#" + timeID);
            app.actualSample.createHDRFromSpectrumAndMap(spectrum, map);


            searchMap(app);
            refreshApp(app);

            function button1Callback()
                fileSpectrum = getFile(app);
                if isempty(fileSpectrum)
                    return;
                end
                spectrum = fileSpectrum;
                btn1.BackgroundColor = [0.2, 0.8, 0.2];
                [app.lastPath, ~, ~] = fileparts(fileSpectrum);
            end

            function button2Callback()
                fileMap = getFile(app);
                if isempty(fileMap)
                    return;
                end
                map = fileMap;
                btn2.BackgroundColor = [0.2, 0.8, 0.2];
                [app.lastPath, ~, ~] = fileparts(fileMap);
            end

            function button3Callback()
                closeDialog();
            end

            function closeDialog()
                uiresume(d);
                delete(d);
            end

        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            rect = drawrectangle(app.UIAxes);
            rect = rect.Position;
            app.actualSample.cropImage(rect)
            refreshApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.ViewrangeLabel = uilabel(app.UIFigure);
            app.ViewrangeLabel.Text = 'View range';
            app.bottomLimit = uieditfield(app.UIFigure, 'numeric');
            app.bottomLimit.ValueChangedFcn = createCallbackFcn(app, @bottomLimitValueChanged, true);
            app.bottomLimit.Value = -Inf;
            app.toLabel = uilabel(app.UIFigure);
            app.toLabel.Text = 'to';
            app.upperLimit = uieditfield(app.UIFigure, 'numeric');
            app.upperLimit.ValueChangedFcn = createCallbackFcn(app, @upperLimitValueChanged, true);
            app.upperLimit.Value = Inf;
            app.Log10scaleDropDownLabel = uilabel(app.UIFigure);
            app.Log10scaleDropDownLabel.Text = 'Log10 scale';
            app.Log10scaleDropDown = uidropdown(app.UIFigure);
            app.Log10scaleDropDown.ValueChangedFcn = createCallbackFcn(app, @Log10scaleDropDownValueChanged, true);
            app.ColormapscaleDropDownLabel = uilabel(app.UIFigure);
            app.ColormapscaleDropDownLabel.Text = 'Color map  scale';
            app.ColormapscaleDropDown = uidropdown(app.UIFigure);
            app.ColormapscaleDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapscaleDropDownValueChanged, true);
            app.ColormapscaleDropDown.Value = 'Option 3';
            app.ExtensionDropDownLabel = uilabel(app.UIFigure);
            app.ExtensionDropDownLabel.Text = 'Extension';
            app.ExtensionDropDown = uidropdown(app.UIFigure);
            app.IncludesubfolderDropDownLabel = uilabel(app.UIFigure);
            app.IncludesubfolderDropDownLabel.Text = 'Include subfolder';
            app.IncludesubfolderDropDown = uidropdown(app.UIFigure);
            app.ModelDropDownLabel = uilabel(app.UIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.AddluminancemapButton = uibutton(app.UIFigure, 'push');
            app.AddluminancemapButton.ButtonPushedFcn = createCallbackFcn(app, @AddluminancemapButtonPushed, true);
            app.AddluminancemapButton.Text = 'Add luminance map';
            app.MapNameInput = uieditfield(app.UIFigure, 'text');
            app.MapNameInput.Placeholder = 'New luminance map name';
            app.LuminancemapsListBoxLabel = uilabel(app.UIFigure);
            app.LuminancemapsListBoxLabel.Text = 'Luminance maps';
            app.LuminancemapsListBox = uilistbox(app.UIFigure);
            app.LuminancemapsListBox.Items = {};
            app.LuminancemapsListBox.ClickedFcn = createCallbackFcn(app, @LuminancemapsListBoxClicked, true);
            app.LuminancemapsListBox.Value = {};
            app.DeleteactualluminancemapButton = uibutton(app.UIFigure, 'push');
            app.DeleteactualluminancemapButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteactualluminancemapButtonPushed, true);
            app.DeleteactualluminancemapButton.Text = 'Delete actual luminance map';
            app.SpectrummapButton = uibutton(app.UIFigure, 'push');
            app.SpectrummapButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrummapButtonPushed, true);
            app.SpectrummapButton.Text = 'Spectrum & map';
            app.AddROIButton = uibutton(app.UIFigure, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Text = 'Add ROI';
            app.ROINameInput = uieditfield(app.UIFigure, 'text');
            app.ROINameInput.Placeholder = 'ROI name';
            app.DeleteROIButton = uibutton(app.UIFigure, 'push');
            app.DeleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIButtonPushed, true);
            app.DeleteROIButton.Text = 'Delete ROI';
            app.ROInumberEditFieldLabel = uilabel(app.UIFigure);
            app.ROInumberEditFieldLabel.Text = 'ROI number';
            app.ROInumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.AddrecalibrationareaButton = uibutton(app.UIFigure, 'push');
            app.AddrecalibrationareaButton.ButtonPushedFcn = createCallbackFcn(app, @AddrecalibrationareaButtonPushed, true);
            app.AddrecalibrationareaButton.Text = 'Add recalibration area';
            app.RecalibrationValueEditFieldLabel = uilabel(app.UIFigure);
            app.RecalibrationValueEditFieldLabel.Text = 'RecalibrationValue';
            app.RecalibrationValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ActualrecalibrationvalueLabel = uilabel(app.UIFigure);
            app.ActualrecalibrationvalueLabel.Text = 'Actual recalibration value: ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.AppmessageLabel = uilabel(app.UIFigure);
            app.AppmessageLabel.Text = 'App message...';
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LuminanceMapManager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end